classdef Interfaz_Grafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PrincipalTab                   matlab.ui.container.Tab
        MiniProyectobalancnLabel       matlab.ui.control.Label
        EstadoActualButton             matlab.ui.control.Button
        RecopilacindedatosButton       matlab.ui.control.Button
        VariacindeparmetrosButton      matlab.ui.control.Button
        EstadoTab                      matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        EstadoActualLabel              matlab.ui.control.Label
        RegresarButton                 matlab.ui.control.Button
        ValorAngulo                    matlab.ui.control.Label
        oLabel                         matlab.ui.control.Label
        AnguloLabel                    matlab.ui.control.Label
        GyroscopioLabel                matlab.ui.control.Label
        AcelermetroLabel               matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        Gx                             matlab.ui.control.Label
        Gy                             matlab.ui.control.Label
        Gz                             matlab.ui.control.Label
        Ax                             matlab.ui.control.Label
        Ay                             matlab.ui.control.Label
        Az                             matlab.ui.control.Label
        DatosTab                       matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        DatosLabel                     matlab.ui.control.Label
        RegresarButton_2               matlab.ui.control.Button
        DatosagraficarSliderLabel      matlab.ui.control.Label
        N                              matlab.ui.control.Slider
        GuardarloDatosLabel            matlab.ui.control.Label
        QunombredeseaparasuarchivoLabel  matlab.ui.control.Label
        NombreDatos                    matlab.ui.control.EditField
        CuantosdatosdeseaguardarLabel  matlab.ui.control.Label
        DatosGuardar                   matlab.ui.control.NumericEditField
        GuardarButton                  matlab.ui.control.Button
        Nlabel                         matlab.ui.control.Label
        ParametrosTab                  matlab.ui.container.Tab
        ParmetrosdelSistemaLabel       matlab.ui.control.Label
        RegresarButton_3               matlab.ui.control.Button
        KpEditFieldLabel               matlab.ui.control.Label
        Kp                             matlab.ui.control.NumericEditField
        KiEditFieldLabel               matlab.ui.control.Label
        Ki                             matlab.ui.control.NumericEditField
        KdEditFieldLabel               matlab.ui.control.Label
        Kd                             matlab.ui.control.NumericEditField
        ControladorPIDLabel            matlab.ui.control.Label
        ActualizarKpButton             matlab.ui.control.Button
        ActualizarKiButton             matlab.ui.control.Button
        ActualizarKdButton             matlab.ui.control.Button
        PrecisinDropDownLabel          matlab.ui.control.Label
        PrecisionKp                    matlab.ui.control.DropDown
        PrecisinDropDown_2Label        matlab.ui.control.Label
        PrecisionKi                    matlab.ui.control.DropDown
        PrecisinDropDown_3Label        matlab.ui.control.Label
        PrecisionKd                    matlab.ui.control.DropDown
        ActualizartodosButton          matlab.ui.control.Button
        ReferenciaSliderLabel          matlab.ui.control.Label
        ReferenciaSlider               matlab.ui.control.Slider
    end

    
    properties (Access = private)
        s % Description
    end
    
    methods (Access = private)
        
        function results = key(app,a,b)
            if (read(app.s,1,'uint8')==a)
                if (read(app.s,1,'uint8')==b)
                    if (read(app.s,1,'uint8')==b)
                        results = 1;
                    else
                        results = 0;
                    end
                else
                    results = 0;
                end
            else
                results = 0;
            end
        end
        
        function results = SendFloat(app,x,codigo,precision)
            x = x*10^precision;
            entero = floor(x);
            decimal = (x-entero)*10000;
            
            write(app.s,codigo,'uint8');
            write(app.s,int16(entero),'uint16');
            write(app.s,int16(decimal),'uint16');
            write(app.s,precision,'uint8');
        end
        
        function results = Send(app,x,codigo,Pvalue)
            if Pvalue == "XXXX.XXXX"
                SendFloat(app,x,codigo,0);
            elseif Pvalue == "XXX.XXXXX"
                SendFloat(app,x,codigo,1);
            elseif Pvalue == "XX.XXXXXX"
                SendFloat(app,x,codigo,2);
            elseif Pvalue == "X.XXXXXXX"
                SendFloat(app,x,codigo,3);
            elseif Pvalue == "0.XXXXXXXX"
                SendFloat(app,x,codigo,4);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Datos
            Datos = zeros(1,100);
            app.s = serialport('COM3',115200);
            flush(app.s);
        end

        % Button pushed function: EstadoActualButton
        function EstadoActualButtonPushed(app, event)
            global detenerEstado
            detenerEstado = 0;
            theta_anterior = 0;
            app.TabGroup.SelectedTab = app.EstadoTab;
            L = 10;
            g = 0.7;
            ax = 8;
            ay = 10;
            while(detenerEstado==0)
                write(app.s,4,'uint8');
                pause(0.1);
                if (app.s.NumBytesAvailable)>16
                    if (key(app,10,3)==1)
                        theta = read(app.s,1,'int16');
                        app.ValorAngulo.Text = string(theta);
                        app.Ax.Text = string(read(app.s,1,'int16'));
                        app.Ay.Text = string(read(app.s,1,'int16'));
                        app.Az.Text = string(read(app.s,1,'int16'));
                        app.Gx.Text = string(read(app.s,1,'int16'));
                        app.Gy.Text = string(read(app.s,1,'int16'));
                        app.Gz.Text = string(read(app.s,1,'int16'));
                    end
                end
                bx = ax + L*cosd(theta);
                by = ay + L*sind(theta);
                cx = bx + g*cosd(theta+90);
                cy = by + g*sind(theta+90);
                dx = ax + g*cosd(theta+90);
                dy = ay + g*sind(theta+90);
                plot(app.UIAxes2,[6,6;0,14],'k',"LineWidth",5)
                hold (app.UIAxes2,"on")
                plot(app.UIAxes2,[6,8.3;10,10],'k',"LineWidth",5)
                plot(app.UIAxes2, [ax,bx;bx,cx;cx,dx;dx,ax],[ay,by;by,cy;cy,dy;dy,ay],'r','LineWidth',2.5);
                hold (app.UIAxes2,"off")
            end
        end

        % Button pushed function: RecopilacindedatosButton
        function RecopilacindedatosButtonPushed(app, event)
            global detenerDatos Datos 
            detenerDatos = 0;
            n = round(app.N.Value);
            app.TabGroup.SelectedTab = app.DatosTab;
%             h = plot(app.UIAxes,1:app.N.Value,Datos((101-app.N.Value):100))
            while (detenerDatos==0)
                write(app.s,2,'uint8')
                pause(0.2);
                if (app.s.NumBytesAvailable)>4
                    if (key(app,2,4)==1)
                        Datos = circshift(Datos,-1);
                        Datos(100) = read(app.s,1,'int16');
                    end
                end
                plot(app.UIAxes,1:n,Datos((101-n):100))
%                 h.YData(1:app.N.Value) = Datos((101-app.N.Value):100);
                drawnow limitrate
                app.Nlabel.Text = string(n);
            end
        end

        % Button pushed function: VariacindeparmetrosButton
        function VariacindeparmetrosButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParametrosTab;
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            global detenerEstado
            detenerEstado = 1;
            app.TabGroup.SelectedTab = app.PrincipalTab;
        end

        % Button pushed function: RegresarButton_2
        function RegresarButton_2Pushed(app, event)
            global detenerDatos
            detenerDatos = 1;
            app.TabGroup.SelectedTab = app.PrincipalTab;
        end

        % Value changed function: N
        function NValueChanged(app, event)
            value = app.N.Value;
            app.UIAxes.XLim = [1,2*value];
        end

        % Callback function
        function resetButtonPushed(app, event)
            global Datos
            Datos = zeros(1,100);
            flush(app.s);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            global Datos
            file = fopen([app.NombreDatos.Value,'.txt'],'w');
            fprintf(file,"Datos del Ángulo del balancin \n");
            for n=1:app.DatosGuardar.Value
                fprintf(file,string(Datos(100-app.DatosGuardar.Value+n))+"\n");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Button pushed function: ActualizarKpButton
        function ActualizarKpButtonPushed(app, event)
            kP = app.Kp.Value;
            Send(app,kP,12,app.PrecisionKp.Value);
        end

        % Button pushed function: ActualizarKiButton
        function ActualizarKiButtonPushed(app, event)
            kI = app.Ki.Value;
            Send(app,kI,13,app.PrecisionKi.Value);
        end

        % Button pushed function: ActualizarKdButton
        function ActualizarKdButtonPushed(app, event)
            kD = app.Kd.Value;
            Send(app,kD,14,app.PrecisionKd.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrincipalTab = uitab(app.TabGroup);
            app.PrincipalTab.Title = 'Principal';
            app.MiniProyectobalancnLabel = uilabel(app.PrincipalTab);
            app.MiniProyectobalancnLabel.Text = 'Mini-Proyecto: balancín';
            app.EstadoActualButton = uibutton(app.PrincipalTab, 'push');
            app.EstadoActualButton.ButtonPushedFcn = createCallbackFcn(app, @EstadoActualButtonPushed, true);
            app.EstadoActualButton.Text = 'Estado Actual';
            app.RecopilacindedatosButton = uibutton(app.PrincipalTab, 'push');
            app.RecopilacindedatosButton.ButtonPushedFcn = createCallbackFcn(app, @RecopilacindedatosButtonPushed, true);
            app.RecopilacindedatosButton.Text = {'Recopilación'; 'de datos'};
            app.VariacindeparmetrosButton = uibutton(app.PrincipalTab, 'push');
            app.VariacindeparmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @VariacindeparmetrosButtonPushed, true);
            app.VariacindeparmetrosButton.Text = {'Variación de'; 'parámetros'};
            app.EstadoTab = uitab(app.TabGroup);
            app.EstadoTab.Title = 'Estado';
            app.UIAxes2 = uiaxes(app.EstadoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [0 25];
            app.UIAxes2.YLim = [6 15];
            app.EstadoActualLabel = uilabel(app.EstadoTab);
            app.EstadoActualLabel.Text = 'Estado Actual';
            app.RegresarButton = uibutton(app.EstadoTab, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.ValorAngulo = uilabel(app.EstadoTab);
            app.ValorAngulo.Text = '0';
            app.oLabel = uilabel(app.EstadoTab);
            app.oLabel.Text = 'o';
            app.AnguloLabel = uilabel(app.EstadoTab);
            app.AnguloLabel.Text = 'Ángulo: ';
            app.GyroscopioLabel = uilabel(app.EstadoTab);
            app.GyroscopioLabel.Text = 'Gyroscopio';
            app.AcelermetroLabel = uilabel(app.EstadoTab);
            app.AcelermetroLabel.Text = 'Acelerómetro';
            app.XLabel = uilabel(app.EstadoTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.EstadoTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.EstadoTab);
            app.ZLabel.Text = 'Z';
            app.Gx = uilabel(app.EstadoTab);
            app.Gx.Text = '0000';
            app.Gy = uilabel(app.EstadoTab);
            app.Gy.Text = '0000';
            app.Gz = uilabel(app.EstadoTab);
            app.Gz.Text = '0000';
            app.Ax = uilabel(app.EstadoTab);
            app.Ax.Text = '0000';
            app.Ay = uilabel(app.EstadoTab);
            app.Ay.Text = '0000';
            app.Az = uilabel(app.EstadoTab);
            app.Az.Text = '0000';
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.UIAxes = uiaxes(app.DatosTab);
            title(app.UIAxes, 'Angulo')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Angulo')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-90 90];
            app.DatosLabel = uilabel(app.DatosTab);
            app.DatosLabel.Text = 'Datos';
            app.RegresarButton_2 = uibutton(app.DatosTab, 'push');
            app.RegresarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RegresarButton_2Pushed, true);
            app.RegresarButton_2.Text = 'Regresar';
            app.DatosagraficarSliderLabel = uilabel(app.DatosTab);
            app.DatosagraficarSliderLabel.Text = {'Datos a'; 'graficar'};
            app.N = uislider(app.DatosTab);
            app.N.Limits = [5 100];
            app.N.ValueChangedFcn = createCallbackFcn(app, @NValueChanged, true);
            app.N.Value = 5;
            app.GuardarloDatosLabel = uilabel(app.DatosTab);
            app.GuardarloDatosLabel.Text = 'Guardar lo Datos';
            app.QunombredeseaparasuarchivoLabel = uilabel(app.DatosTab);
            app.QunombredeseaparasuarchivoLabel.Text = {'¿Qué nombre desea'; ' para su archivo?'};
            app.NombreDatos = uieditfield(app.DatosTab, 'text');
            app.CuantosdatosdeseaguardarLabel = uilabel(app.DatosTab);
            app.CuantosdatosdeseaguardarLabel.Text = {'¿Cuantos datos'; 'desea guardar?'};
            app.DatosGuardar = uieditfield(app.DatosTab, 'numeric');
            app.DatosGuardar.Limits = [1 100];
            app.DatosGuardar.RoundFractionalValues = 'on';
            app.DatosGuardar.Value = 1;
            app.GuardarButton = uibutton(app.DatosTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Nlabel = uilabel(app.DatosTab);
            app.Nlabel.Text = '5';
            app.ParametrosTab = uitab(app.TabGroup);
            app.ParametrosTab.Title = 'Parametros';
            app.ParmetrosdelSistemaLabel = uilabel(app.ParametrosTab);
            app.ParmetrosdelSistemaLabel.Text = {'Parámetros del '; 'Sistema '};
            app.RegresarButton_3 = uibutton(app.ParametrosTab, 'push');
            app.RegresarButton_3.Text = 'Regresar';
            app.KpEditFieldLabel = uilabel(app.ParametrosTab);
            app.KpEditFieldLabel.Text = 'Kp';
            app.Kp = uieditfield(app.ParametrosTab, 'numeric');
            app.Kp.Limits = [0 Inf];
            app.KiEditFieldLabel = uilabel(app.ParametrosTab);
            app.KiEditFieldLabel.Text = 'Ki';
            app.Ki = uieditfield(app.ParametrosTab, 'numeric');
            app.Ki.Limits = [0 Inf];
            app.KdEditFieldLabel = uilabel(app.ParametrosTab);
            app.KdEditFieldLabel.Text = 'Kd';
            app.Kd = uieditfield(app.ParametrosTab, 'numeric');
            app.ControladorPIDLabel = uilabel(app.ParametrosTab);
            app.ControladorPIDLabel.Text = 'Controlador PID';
            app.ActualizarKpButton = uibutton(app.ParametrosTab, 'push');
            app.ActualizarKpButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarKpButtonPushed, true);
            app.ActualizarKpButton.Text = 'Actualizar Kp';
            app.ActualizarKiButton = uibutton(app.ParametrosTab, 'push');
            app.ActualizarKiButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarKiButtonPushed, true);
            app.ActualizarKiButton.Text = 'Actualizar Ki';
            app.ActualizarKdButton = uibutton(app.ParametrosTab, 'push');
            app.ActualizarKdButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarKdButtonPushed, true);
            app.ActualizarKdButton.Text = 'Actualizar Kd';
            app.PrecisinDropDownLabel = uilabel(app.ParametrosTab);
            app.PrecisinDropDownLabel.Text = 'Precisión';
            app.PrecisionKp = uidropdown(app.ParametrosTab);
            app.PrecisionKp.Items = {'XXXX.XXXX', 'XXX.XXXXX', 'XX.XXXXXX', 'X.XXXXXXX', '0.XXXXXXXX'};
            app.PrecisionKp.Tooltip = {'La precisión se representa con la cantidad de digitos que se requieren antes y después del punto decimal. Se representan con X''s'};
            app.PrecisionKp.Value = 'XXXX.XXXX';
            app.PrecisinDropDown_2Label = uilabel(app.ParametrosTab);
            app.PrecisinDropDown_2Label.Text = 'Precisión';
            app.PrecisionKi = uidropdown(app.ParametrosTab);
            app.PrecisionKi.Items = {'XXXX.XXXX', 'XXX.XXXXX', 'XX.XXXXXX', 'X.XXXXXXX', '0.XXXXXXXX'};
            app.PrecisionKi.Tooltip = {'La precisión se representa con la cantidad de digitos que se requieren antes y después del punto decimal. Se representan con X''s'};
            app.PrecisionKi.Value = 'XXXX.XXXX';
            app.PrecisinDropDown_3Label = uilabel(app.ParametrosTab);
            app.PrecisinDropDown_3Label.Text = 'Precisión';
            app.PrecisionKd = uidropdown(app.ParametrosTab);
            app.PrecisionKd.Items = {'XXXX.XXXX', 'XXX.XXXXX', 'XX.XXXXXX', 'X.XXXXXXX', '0.XXXXXXXX'};
            app.PrecisionKd.Tooltip = {'La precisión se representa con la cantidad de digitos que se requieren antes y después del punto decimal. Se representan con X''s'};
            app.PrecisionKd.Value = 'XXXX.XXXX';
            app.ActualizartodosButton = uibutton(app.ParametrosTab, 'push');
            app.ActualizartodosButton.Text = 'Actualizar todos';
            app.ReferenciaSliderLabel = uilabel(app.ParametrosTab);
            app.ReferenciaSliderLabel.Text = 'Referencia';
            app.ReferenciaSlider = uislider(app.ParametrosTab);
            app.ReferenciaSlider.Limits = [-19 19];
            app.ReferenciaSlider.Value = 0.7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_Grafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end